classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        ControlpanelLabel   matlab.ui.control.Label
        TabGroup2           matlab.ui.container.TabGroup
        ControlsTab         matlab.ui.container.Tab
        KPIsTab             matlab.ui.container.Tab
        RightPanel          matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        CandlesTab          matlab.ui.container.Tab
        UIAxesCandles       matlab.ui.control.UIAxes
        UIAxesPerformance   matlab.ui.control.UIAxes
        IntraCandlesTab     matlab.ui.container.Tab
        UIAxesIntraCandles  matlab.ui.control.UIAxes
        AssetsOrdersTab     matlab.ui.container.Tab
        UItblAssets         matlab.ui.control.Table
        UItblOrders         matlab.ui.control.Table
        AssetsLabel         matlab.ui.control.Label
        OrderlistLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function updateKlineAxes(app, kline_timetable)
            uiAxesObj = app.UIAxesCandles;
            
            hold(uiAxesObj,'on')
            candle(uiAxesObj, kline_timetable) %plot candle sticks
            
            %now create bollinger bands
            [middle,upper,lower]= bollinger(kline_timetable);
            CloseBolling = [middle.Close, upper.Close, lower.Close];
            plot(uiAxesObj, middle.Time, CloseBolling)
            hold(uiAxesObj,'off')

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {722, 722};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ControlpanelLabel = uilabel(app.LeftPanel);
            app.ControlpanelLabel.Text = 'Control panel';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.ControlsTab = uitab(app.TabGroup2);
            app.ControlsTab.Title = 'Controls';
            app.KPIsTab = uitab(app.TabGroup2);
            app.KPIsTab.Title = 'KPIs';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.CandlesTab = uitab(app.TabGroup);
            app.CandlesTab.Title = 'Candles';
            app.UIAxesCandles = uiaxes(app.CandlesTab);
            title(app.UIAxesCandles, 'Candles')
            xlabel(app.UIAxesCandles, 'X')
            ylabel(app.UIAxesCandles, 'Y')
            zlabel(app.UIAxesCandles, 'Z')
            app.UIAxesPerformance = uiaxes(app.CandlesTab);
            title(app.UIAxesPerformance, 'Performance')
            xlabel(app.UIAxesPerformance, 'X')
            ylabel(app.UIAxesPerformance, 'Y')
            zlabel(app.UIAxesPerformance, 'Z')
            app.IntraCandlesTab = uitab(app.TabGroup);
            app.IntraCandlesTab.Title = 'Intra-Candles';
            app.UIAxesIntraCandles = uiaxes(app.IntraCandlesTab);
            title(app.UIAxesIntraCandles, 'Intra-Candles')
            xlabel(app.UIAxesIntraCandles, 'X')
            ylabel(app.UIAxesIntraCandles, 'Y')
            zlabel(app.UIAxesIntraCandles, 'Z')
            app.AssetsOrdersTab = uitab(app.TabGroup);
            app.AssetsOrdersTab.Title = 'Assets/Orders';
            app.UItblAssets = uitable(app.AssetsOrdersTab);
            app.UItblAssets.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UItblAssets.RowName = {};
            app.UItblOrders = uitable(app.AssetsOrdersTab);
            app.UItblOrders.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UItblOrders.RowName = {};
            app.AssetsLabel = uilabel(app.AssetsOrdersTab);
            app.AssetsLabel.Text = 'Assets';
            app.OrderlistLabel = uilabel(app.AssetsOrdersTab);
            app.OrderlistLabel.Text = 'Order list';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end